classdef ColorPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label_6                 matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        AdjustsavedcolorLabel   matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        HSVLabel                matlab.ui.control.Label
        RGBHexLabel             matlab.ui.control.Label
        RGB01Label              matlab.ui.control.Label
        RGB0255Label            matlab.ui.control.Label
        HSV                     matlab.ui.control.EditField
        Preview                 matlab.ui.control.EditField
        PressStosavecolorLabel  matlab.ui.control.Label
        RGB255                  matlab.ui.control.EditField
        RGB01                   matlab.ui.control.EditField
        RGB16                   matlab.ui.control.EditField
        Save                    matlab.ui.control.EditField
        BSpinner                matlab.ui.control.Spinner
        BSpinnerLabel           matlab.ui.control.Label
        GSpinner                matlab.ui.control.Spinner
        GSpinnerLabel           matlab.ui.control.Label
        RSpinner                matlab.ui.control.Spinner
        RSpinnerLabel           matlab.ui.control.Label
        VSpinner                matlab.ui.control.Spinner
        VSpinnerLabel           matlab.ui.control.Label
        SSpinner                matlab.ui.control.Spinner
        SSpinnerLabel           matlab.ui.control.Label
        HSpinner                matlab.ui.control.Spinner
        HSpinnerLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        mTimer
        mouseInfo
        robot

        RedPreview
        GreenPreview
        BluePreview

        RedSave
        GreenSave
        BlueSave

        pt
    end
    
    methods (Access = private)

        function timerInit(app)
            app.mTimer = timer;
            app.mTimer.Period = 0.02;
            app.mTimer.ExecutionMode = 'fixedSpacing';
            app.mTimer.TimerFcn = @(~, ~) timerHandler(app);
            start(app.mTimer);
        end

        function timerHandler(app)
            app.pt = java.awt.MouseInfo.getPointerInfo().getLocation();
            app.previewColor;
        end
        
        function previewColor(app)

            if ~isempty(app.pt)
                color = app.robot.getPixelColor(app.pt.x, app.pt.y);
                [app.RedPreview, app.GreenPreview, app.BluePreview] = deal(color.getRed(), color.getGreen(), color.getBlue());
                app.Preview.BackgroundColor = [app.RedPreview, app.GreenPreview, app.BluePreview] / 255;
            end
            
        end

        function updateSaveS(app)
            % get current rgb values
            rgb255 = [app.RedPreview, app.GreenPreview, app.BluePreview];
            rgb01 = rgb255 / 255;

            % update saved rgb values
            app.RedSave  = rgb255(1);
            app.GreenSave = rgb255(2);
            app.BlueSave = rgb255(3);

            % update rgb spinner
            app.RSpinner.Value = rgb255(1);
            app.GSpinner.Value = rgb255(2);
            app.BSpinner.Value = rgb255(3);
            
            % update rgb tag
            app.RGB255.Value = [num2str(rgb255(1)), ' ', ...
                                num2str(rgb255(2)), ' ', ...
                                num2str(rgb255(3))];
            app.RGB01.Value = [num2str(roundn(rgb01(1), -2)), ' ', ...
                               num2str(roundn(rgb01(2), -2)), ' ', ...
                               num2str(roundn(rgb01(3), -2))];
            temp = dec2hex(rgb255, 2)'; app.RGB16.Value = ['#', temp(:)'];

            % get HSV
            hsv = rgb2hsv(rgb01);

            % update hsv spinner
            temp = 0:180; app.HSpinner.Value = temp(dsearchn(temp(:), hsv(1) * 180));
            temp = 0:255; app.SSpinner.Value = temp(dsearchn(temp(:), hsv(2) * 255));
            temp = 0:255; app.VSpinner.Value = temp(dsearchn(temp(:), hsv(3) * 255));

            % update hsv tag
            app.HSV.Value = [num2str(app.HSpinner.Value), ' ', ...
                             num2str(app.SSpinner.Value), ' ', ...
                             num2str(app.VSpinner.Value)];

            % update saved background color
            app.Save.BackgroundColor = rgb01;
        end
        
        function updateSaveRgb(app)
            % get saved rgb values
            rgb255 = [app.RedSave, app.GreenSave, app.BlueSave];
            rgb01 = rgb255 / 255;

            % update rgb spinner
            app.RSpinner.Value = rgb255(1);
            app.GSpinner.Value = rgb255(2);
            app.BSpinner.Value = rgb255(3);
            
            % update rgb tag
            app.RGB255.Value = [num2str(rgb255(1)), ' ', ...
                                num2str(rgb255(2)), ' ', ...
                                num2str(rgb255(3))];
            app.RGB01.Value = [num2str(roundn(rgb01(1), -2)), ' ', ...
                               num2str(roundn(rgb01(2), -2)), ' ', ...
                               num2str(roundn(rgb01(3), -2))];
            temp = dec2hex(rgb255, 2)'; app.RGB16.Value = ['#', temp(:)'];

            % get HSV
            hsv = rgb2hsv(rgb01);

            % update hsv spinner
            temp = 0:180; app.HSpinner.Value = temp(dsearchn(temp(:), hsv(1) * 180));
            temp = 0:255; app.SSpinner.Value = temp(dsearchn(temp(:), hsv(2) * 255));
            temp = 0:255; app.VSpinner.Value = temp(dsearchn(temp(:), hsv(3) * 255));

            % update hsv tag
            app.HSV.Value = [num2str(app.HSpinner.Value), ' ', ...
                             num2str(app.SSpinner.Value), ' ', ...
                             num2str(app.VSpinner.Value)];

            % update saved background color
            app.Save.BackgroundColor = rgb01;
        end

        function updateSaveHsv(app)
            % get current hsv
            hsv = [app.HSpinner.Value / 180, app.SSpinner.Value / 255, app.VSpinner.Value / 255];

            % convert to rgb
            rgb01 = hsv2rgb(hsv);
            temp = 0:255;
            rgb255 = temp(dsearchn(temp(:), rgb01(:) * 255));

            % update saved rgb values
            app.RedSave  = rgb255(1);
            app.GreenSave = rgb255(2);
            app.BlueSave = rgb255(3);

            % update rgb spinner
            app.RSpinner.Value = rgb255(1);
            app.GSpinner.Value = rgb255(2);
            app.BSpinner.Value = rgb255(3);
            
            % update rgb tag
            app.RGB255.Value = [num2str(rgb255(1)), ' ', ...
                                num2str(rgb255(2)), ' ', ...
                                num2str(rgb255(3))];
            app.RGB01.Value = [num2str(roundn(rgb01(1), -2)), ' ', ...
                               num2str(roundn(rgb01(2), -2)), ' ', ...
                               num2str(roundn(rgb01(3), -2))];
            temp = dec2hex(rgb255, 2)'; app.RGB16.Value = ['#', temp(:)'];

            % update hsv tag
            app.HSV.Value = [num2str(app.HSpinner.Value), ' ', ...
                             num2str(app.SSpinner.Value), ' ', ...
                             num2str(app.VSpinner.Value)];

            % update saved background color
            app.Save.BackgroundColor = rgb01;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import java.awt.MouseInfo
            import java.awt.Robot

            app.UIFigure.Name = 'Color picker';
            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;
            
            app.RedPreview = 255;
            app.GreenPreview = 255;
            app.BluePreview = 255;
            app.RedSave = 255;
            app.GreenSave = 255;
            app.BlueSave = 255;
            app.previewColor;
            app.updateSaveRgb;

            app.robot = java.awt.Robot();
            app.timerInit;
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;

            switch key
                case 's'
                    app.updateSaveS;
            end

        end

        % Value changed function: RSpinner
        function RSpinnerValueChanged(app, event)
            app.RedPreview = app.RSpinner.Value;
            app.updateSaveRgb;
        end

        % Value changed function: GSpinner
        function GSpinnerValueChanged(app, event)
            app.GreenPreview = app.GSpinner.Value;
            app.updateSaveRgb;
        end

        % Value changed function: BSpinner
        function BSpinnerValueChanged(app, event)
            app.BluePreview = app.BSpinner.Value;
            app.updateSaveRgb;
        end

        % Value changed function: HSpinner
        function HSpinnerValueChanged(app, event)
            app.updateSaveHsv;
        end

        % Value changed function: SSpinner
        function SSpinnerValueChanged(app, event)
            app.updateSaveHsv;
        end

        % Value changed function: VSpinner
        function VSpinnerValueChanged(app, event)
            app.updateSaveHsv;
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;

            switch key
                case 's'
                    app.updateSaveS;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            try 
                stop(app.mTimer);
                delete(app.mTimer);
            catch ME
                disp(ME.message);
            end

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.HSpinnerLabel = uilabel(app.UIFigure);
            app.HSpinnerLabel.Text = 'H';
            app.HSpinner = uispinner(app.UIFigure);
            app.HSpinner.Limits = [0 180];
            app.HSpinner.ValueDisplayFormat = '%.0f';
            app.HSpinner.ValueChangedFcn = createCallbackFcn(app, @HSpinnerValueChanged, true);
            app.SSpinnerLabel = uilabel(app.UIFigure);
            app.SSpinnerLabel.Text = 'S';
            app.SSpinner = uispinner(app.UIFigure);
            app.SSpinner.Limits = [0 255];
            app.SSpinner.ValueDisplayFormat = '%.0f';
            app.SSpinner.ValueChangedFcn = createCallbackFcn(app, @SSpinnerValueChanged, true);
            app.VSpinnerLabel = uilabel(app.UIFigure);
            app.VSpinnerLabel.Text = 'V';
            app.VSpinner = uispinner(app.UIFigure);
            app.VSpinner.Limits = [0 255];
            app.VSpinner.ValueDisplayFormat = '%.0f';
            app.VSpinner.ValueChangedFcn = createCallbackFcn(app, @VSpinnerValueChanged, true);
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.UIFigure);
            app.RSpinner.Limits = [0 255];
            app.RSpinner.ValueDisplayFormat = '%.0f';
            app.RSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.GSpinnerLabel = uilabel(app.UIFigure);
            app.GSpinnerLabel.Text = 'G';
            app.GSpinner = uispinner(app.UIFigure);
            app.GSpinner.Limits = [0 255];
            app.GSpinner.ValueDisplayFormat = '%.0f';
            app.GSpinner.ValueChangedFcn = createCallbackFcn(app, @GSpinnerValueChanged, true);
            app.BSpinnerLabel = uilabel(app.UIFigure);
            app.BSpinnerLabel.Text = 'B';
            app.BSpinner = uispinner(app.UIFigure);
            app.BSpinner.Limits = [0 255];
            app.BSpinner.ValueDisplayFormat = '%.0f';
            app.BSpinner.ValueChangedFcn = createCallbackFcn(app, @BSpinnerValueChanged, true);
            app.Save = uieditfield(app.UIFigure, 'text');
            app.RGB16 = uieditfield(app.UIFigure, 'text');
            app.RGB16.Editable = 'off';
            app.RGB01 = uieditfield(app.UIFigure, 'text');
            app.RGB01.Editable = 'off';
            app.RGB255 = uieditfield(app.UIFigure, 'text');
            app.RGB255.Editable = 'off';
            app.PressStosavecolorLabel = uilabel(app.UIFigure);
            app.PressStosavecolorLabel.Text = 'Press ''S'' to save color';
            app.Preview = uieditfield(app.UIFigure, 'text');
            app.HSV = uieditfield(app.UIFigure, 'text');
            app.HSV.Editable = 'off';
            app.RGB0255Label = uilabel(app.UIFigure);
            app.RGB0255Label.Text = 'RGB(0~255)';
            app.RGB01Label = uilabel(app.UIFigure);
            app.RGB01Label.Text = 'RGB(0~1)';
            app.RGBHexLabel = uilabel(app.UIFigure);
            app.RGBHexLabel.Text = 'RGB(#Hex)';
            app.HSVLabel = uilabel(app.UIFigure);
            app.HSVLabel.Text = 'HSV';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '(0~180)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '(0~255)';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '(0~255)';
            app.AdjustsavedcolorLabel = uilabel(app.UIFigure);
            app.AdjustsavedcolorLabel.Text = 'Adjust saved color';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '(0~255)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '(0~255)';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '(0~255)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorPicker

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end