classdef Msgbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        msgContent  matlab.ui.control.Label
        OKButton    matlab.ui.control.Button
        msgTitle    matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, content, title, alignment)
            narginchk(2, 4);

            if nargin < 3
                title = "Info";
            end

            if nargin < 4
                alignment = "top-center";
            end

            if strcmpi(alignment, "center")
                vAlign = "center";
                hAlign = "center";
            else
                temp = split(alignment, '-');
                vAlign = temp{1};
                hAlign = temp{2};
            end

            app.msgTitle.Text = title;
            app.msgContent.Text = content;
            app.msgContent.HorizontalAlignment = hAlign;
            app.msgContent.VerticalAlignment = vAlign;

            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            uiwait(app.UIFigure);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            uiresume(app.UIFigure);
            delete(app);
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if ~isempty(key)
                uiresume(app.UIFigure);
                delete(app);
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if ~isempty(key)
                uiresume(app.UIFigure);
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.msgTitle = uilabel(app.UIFigure);
            app.msgTitle.Text = 'Title';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.msgContent = uilabel(app.UIFigure);
            app.msgContent.Text = 'Content';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Msgbox(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end