classdef addLines2AxesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LinePanel             matlab.ui.container.Panel
        SizeSpinner           matlab.ui.control.Spinner
        SizeSpinnerLabel      matlab.ui.control.Label
        SyncXYButton          matlab.ui.control.Button
        AddButton             matlab.ui.control.Button
        MarkerDropDown        matlab.ui.control.DropDown
        MarkerDropDownLabel   matlab.ui.control.Label
        LegendEditField       matlab.ui.control.EditField
        LegendEditFieldLabel  matlab.ui.control.Label
        ColorDropDown         matlab.ui.control.DropDown
        ColorDropDownLabel    matlab.ui.control.Label
        WidthSpinner          matlab.ui.control.Spinner
        WidthSpinnerLabel     matlab.ui.control.Label
        RGBEditField          matlab.ui.control.EditField
        StyleDropDown         matlab.ui.control.DropDown
        StyleDropDownLabel    matlab.ui.control.Label
        YEditField            matlab.ui.control.EditField
        YEditFieldLabel       matlab.ui.control.Label
        TypeDropDown          matlab.ui.control.DropDown
        TypeDropDownLabel     matlab.ui.control.Label
        XEditField            matlab.ui.control.EditField
        XEditFieldLabel       matlab.ui.control.Label
        CancelButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        target % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FigsOrAxes)
            app.UIFigure.Name = 'UI for addLines2Axes';
            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            if strcmp(class(FigsOrAxes), "matlab.ui.Figure") || strcmp(class(FigsOrAxes), "matlab.graphics.Graphics")
                app.target = findobj(FigsOrAxes, "Type", "axes");
            else
                app.target = FigsOrAxes;
            end

            % register DeleteFcn for target
            set(app.target, "DeleteFcn", {@onTargetDeleteFcn, app});
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            type = app.TypeDropDown.Value;
            style = app.StyleDropDown.Value;
            marker = app.MarkerDropDown.Value;
            lineWidth = app.WidthSpinner.Value;
            markerSize = app.SizeSpinner.Value;
            color = app.ColorDropDown.Value;
            legendStr = app.LegendEditField.Value;
            X = app.XEditField.Value;
            Y = app.YEditField.Value;

            switch style
                case "solid"
                    style = "-";
                case "dashed"
                    style = "--";
                case "dotted"
                    style = ":";
                case "dash-dotted"
                    style = "-.";
                otherwise
                    error("Invalid line style");
            end

            if strcmp(color, "custom")
                color = validatecolor(app.RGBEditField.Value);
            end

            if strcmp(type, "vertical")
                X = eval(X);
                X = num2cell(reshape(X, [numel(X), 1]));
                Y = [];
            elseif strcmp(type, "horizontal")
                X = [];
                Y = eval(Y);
                Y = num2cell(reshape(Y, [numel(Y), 1]));
            elseif strcmp(type, "diagonal")
                X = [];
                Y = [];
            elseif strcmp(type, "custom")
                X = eval(X);
                Y = eval(Y);
            end

            lines = struct("X", X, ...
                           "Y", Y, ...
                           "style", style, ...
                           "width", lineWidth, ...
                           "color", color, ...
                           "legend", legendStr, ...
                           "marker", marker, ...
                           "markerSize", markerSize);
            addLines2Axes(app.target, lines);
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            if strcmp(app.ColorDropDown.Value, "custom")
                app.RGBEditField.Enable = "on";
            else
                app.RGBEditField.Enable = "off";
            end
        end

        % Button pushed function: SyncXYButton
        function SyncXYButtonPushed(app, event)
            xRange = scaleAxes(app.target, "x");
            yRange = scaleAxes(app.target, "y");
            xyRange = [min([xRange, yRange]), max([xRange, yRange])];
            scaleAxes(app.target, "x", xyRange);
            scaleAxes(app.target, "y", xyRange);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LinePanel = uipanel(app.UIFigure);
            app.LinePanel.Title = 'Line Panel';
            app.CancelButton = uibutton(app.LinePanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.XEditFieldLabel = uilabel(app.LinePanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LinePanel, 'text');
            app.XEditField.Placeholder = 'e.g. [0,1000,2000]';
            app.TypeDropDownLabel = uilabel(app.LinePanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.LinePanel);
            app.TypeDropDown.Items = {'vertical', 'horizontal', 'diagonal', 'custom'};
            app.TypeDropDown.Value = 'vertical';
            app.YEditFieldLabel = uilabel(app.LinePanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LinePanel, 'text');
            app.YEditField.Placeholder = 'e.g. [0,1000,2000]';
            app.StyleDropDownLabel = uilabel(app.LinePanel);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.LinePanel);
            app.StyleDropDown.Items = {'solid', 'dashed', 'dotted', 'dash-dotted', 'none'};
            app.StyleDropDown.Value = 'solid';
            app.RGBEditField = uieditfield(app.LinePanel, 'text');
            app.RGBEditField.Enable = 'off';
            app.RGBEditField.Placeholder = 'custom color';
            app.WidthSpinnerLabel = uilabel(app.LinePanel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.LinePanel);
            app.WidthSpinner.Step = 0.5;
            app.WidthSpinner.Limits = [0.5 5];
            app.WidthSpinner.ValueDisplayFormat = '%.1f';
            app.WidthSpinner.Value = 1;
            app.ColorDropDownLabel = uilabel(app.LinePanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LinePanel);
            app.ColorDropDown.Items = {'r', 'b', 'k', 'g', 'm', 'y', 'custom'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'k';
            app.LegendEditFieldLabel = uilabel(app.LinePanel);
            app.LegendEditFieldLabel.Text = 'Legend';
            app.LegendEditField = uieditfield(app.LinePanel, 'text');
            app.MarkerDropDownLabel = uilabel(app.LinePanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.LinePanel);
            app.MarkerDropDown.Items = {'o', '*', '+', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '<', '>', 'pentagram', 'hexagram', 'none'};
            app.MarkerDropDown.Value = 'none';
            app.AddButton = uibutton(app.LinePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = {'Add'; ''};
            app.SyncXYButton = uibutton(app.LinePanel, 'push');
            app.SyncXYButton.ButtonPushedFcn = createCallbackFcn(app, @SyncXYButtonPushed, true);
            app.SyncXYButton.Text = 'Sync X&Y';
            app.SizeSpinnerLabel = uilabel(app.LinePanel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.LinePanel);
            app.SizeSpinner.Limits = [0 Inf];
            app.SizeSpinner.ValueDisplayFormat = '%.0f';
            app.SizeSpinner.Value = 6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addLines2AxesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end