classdef figureViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SliderHorizontal  matlab.ui.control.Slider
        SliderVertical    matlab.ui.control.Slider
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imNames
        orientation
        images
        totalHeight
        totalWidth
        windowHeight
        windowWidth
        currentPosition % [left, bottom]
        nImages
    end
    
    methods (Access = private)
        
        function updateImage(app)
            app.currentPosition = roundn([(app.SliderHorizontal.Value - 1) / app.nImages * app.totalWidth, ...
                                          (app.nImages - app.SliderVertical.Value) / app.nImages * app.totalHeight], 0);
            temp = app.images(app.currentPosition(2) + 1:app.currentPosition(2) + app.windowHeight, ...
                              app.currentPosition(1) + 1:app.currentPosition(1) + app.windowWidth, :);
            imshow(temp, "Parent", app.UIAxes, "Border", "tight");
            app.UIAxes.XLimitMethod = "tight";
            app.UIAxes.YLimitMethod = "tight";
            
            if strcmpi(app.orientation, "vertical")
                n = roundn(app.nImages - app.SliderVertical.Value + 1, 0);
            else
                n = roundn(app.SliderHorizontal.Value, 0);
            end

            title(app.UIAxes, app.imNames{n}, "FontSize", 14, "FontWeight", "bold", "Interpreter", "none");
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srcPaths, varargin)
            narginchk(2, 3);

            if nargin < 3
                app.orientation = "vertical";
            else
                app.orientation = varargin{1};
            end

            app.UIFigure.Name = 'figure viewer v1.0';
            app.UIFigure.WindowState = "maximized";
            drawnow;
            app.UIAxes.Units = "normalize";
            app.UIAxes.Position = [0.02, 0.02, 0.96, 0.96];
            drawnow;

            srcPaths = cellstr(srcPaths);
            app.imNames = srcPaths;
            app.nImages = length(srcPaths);
            
            temp = cell(app.nImages, 1);
            for index = 1:app.nImages
                temp{index, 1} = imread(srcPaths{index});
            end
            
            app.windowHeight = max(cellfun(@(x) size(x, 1), temp));
            app.windowWidth = max(cellfun(@(x) size(x, 2), temp));

            if strcmpi(app.orientation, "vertical")
                app.images = vertcat(temp{:});
                app.totalHeight = size(app.images, 1);
                app.totalWidth = size(app.images, 2);

                app.SliderVertical.Limits = [1, app.nImages];
                app.SliderVertical.Value = app.nImages;

                app.SliderHorizontal.Value = 1;
                app.SliderHorizontal.Enable = "off";
            else % horizontal
                app.images = horzcat(temp{:});
                app.totalHeight = size(app.images, 1);
                app.totalWidth = size(app.images, 2);

                app.SliderHorizontal.Limits = [1, app.nImages];
                app.SliderHorizontal.Value = 1;

                app.SliderVertical.Value = app.nImages;
                app.SliderVertical.Enable = "off";
            end

            % show images
            app.updateImage;
        end

        % Value changed function: SliderVertical
        function SliderVerticalValueChanged(app, event)
            app.updateImage;
        end

        % Value changed function: SliderHorizontal
        function SliderHorizontalValueChanged(app, event)
            app.updateImage;
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            if strcmpi(app.orientation, "vertical")
                currentValue = app.SliderVertical.Value;
            else
                currentValue = app.SliderHorizontal.Value;
            end

            scrollDirection = event.VerticalScrollCount;
            
            step = 0.2;
            if scrollDirection > 0
                currentValue = currentValue - step;
            else
                currentValue = currentValue + step;
            end
            
            if strcmpi(app.orientation, "vertical")
                app.SliderVertical.Value = min(max(currentValue, app.SliderVertical.Limits(1)), app.SliderVertical.Limits(2));
            else
                app.SliderHorizontal.Value = min(max(currentValue, app.SliderHorizontal.Limits(1)), app.SliderHorizontal.Limits(2));
            end
            
            app.updateImage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SliderVertical = uislider(app.UIFigure);
            app.SliderVertical.MajorTicks = [];
            app.SliderVertical.ValueChangedFcn = createCallbackFcn(app, @SliderVerticalValueChanged, true);
            app.SliderVertical.MinorTicks = [];
            app.SliderHorizontal = uislider(app.UIFigure);
            app.SliderHorizontal.MajorTicks = [];
            app.SliderHorizontal.ValueChangedFcn = createCallbackFcn(app, @SliderHorizontalValueChanged, true);
            app.SliderHorizontal.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figureViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end