classdef validateInputUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InputValidationPanel  matlab.ui.container.Panel
        TextArea              matlab.ui.control.TextArea
        OKButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        validateFcn
        sInput
    end
    
    properties (Access = public)
        res
    end
    
    methods (Access = private)
        
        function res = validateVal(app, val)
            clearvars ans

            if app.sInput
                try
                    app.validateFcn(val{1});
                    if (exist("ans", "var") && ans) || ~exist("ans", "var")
                        res = true;
                    else
                        res = false;
                    end
                catch
                    res = false;
                end
            else
                try
                    app.validateFcn(eval(val{1}));
                    if (exist("ans", "var") && ans) || ~exist("ans", "var")
                        res = true;
                    else
                        res = false;
                    end
                catch
                    res = false;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, prompt, validateFcn, sInput)
            app.TextArea.Placeholder = prompt;
            app.TextArea.FontColor = [150, 150, 150] / 255; % placeholder color
            app.validateFcn = validateFcn;

            if ~isempty(sInput)
                app.sInput = true;
            else
                app.sInput = false;
            end

            if app.validateVal(app)
                app.OKButton.Enable = "on";
            else
                app.OKButton.Enable = "off";
            end

            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            uiwait(app.UIFigure);
        end

        % Callback function: TextArea
        function TextAreaValueChanging(app, event)
            changingValue = event.Value;
            if isempty(changingValue{1})
                app.TextArea.FontColor = [150, 150, 150] / 255; % placeholder color
            else
                app.TextArea.FontColor = [0, 0, 0];
            end
            if app.validateVal(changingValue)
                app.OKButton.Enable = "on";
            else
                app.OKButton.Enable = "off";
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.sInput
                app.res = app.TextArea.Value{1};
            else
                app.res = eval(app.TextArea.Value{1});
            end
            uiresume(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Prevent from closing
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputValidationPanel = uipanel(app.UIFigure);
            app.InputValidationPanel.Title = 'Input Validation';
            app.OKButton = uibutton(app.InputValidationPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TextArea = uitextarea(app.InputValidationPanel);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.TextArea.Placeholder = 'prompt';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = validateInputUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end